classdef EECE5552_GUI_3D_Simulink < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI                        matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        NumericalData              matlab.ui.container.Panel
        LocateButton               matlab.ui.control.Button
        SetNeighborCountKnob       matlab.ui.control.DiscreteKnob
        SetNeighborCountKnobLabel  matlab.ui.control.Label
        MissingLampIndicator       matlab.ui.control.Lamp
        MissingLampLabel           matlab.ui.control.Label
        LocatedLampIndicator       matlab.ui.control.Lamp
        LocatedLampLabel           matlab.ui.control.Label
        TagCoordinatesPanel        matlab.ui.container.Panel
        ZCoordinateTextArea        matlab.ui.control.TextArea
        ZCoordinateTextAreaLabel   matlab.ui.control.Label
        YCoordinateTextArea        matlab.ui.control.TextArea
        YCoordinateTextAreaLabel   matlab.ui.control.Label
        XCoordinateTextArea        matlab.ui.control.TextArea
        XCoordinateTextAreaLabel   matlab.ui.control.Label
        TagSelector                matlab.ui.container.Panel
        SelectTagDropDown          matlab.ui.control.DropDown
        SelectTagDropDownLabel     matlab.ui.control.Label
        AugmentedRealityDisplay    matlab.ui.container.Panel
        EventLogTextArea           matlab.ui.control.TextArea
        EventLogTextAreaLabel      matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % Set Buffer size and initialize counters 
%         LogBufferSz    = 1000;
%         LogCounter     = 1
        StateBufferSz  = 500;
        StateCounter   = 5; %Starting from 5 -> A previous state is always available for all tags at initialization==0
        
        % Flags
        FoundStateFlag   = 0    % Store state for indicator lights. |||Initially zero -> Not Found|||
        FeasiblilityFlag = 1   % Feasibility check for current triangulated Values
        
        % Room Dimensions
        RoomBoundX     = 5.0  % Actual Room Length  [m]
        RoomBoundY     = 5.0  % Actual Room Breadth [m]
        RoomBoundZ     = 5.0  % Actual Room Height  [m]
        % ScaleFactor    = 1.0  % Actual Dimension to pixel scale
        ScaleFactorX    = 1.0
        ScaleFactorY    = 1.0
        ScaleFactorZ    = 1.0

        
        NeighborCount  = 1    % Number of K-Nearest Neighbors needed when localizing Tag
        TagNumber      = "Tag1"    % Default value of tag to be tracked, updated in callback
        
        % Global State Values
        XPos           = 3.68  %         app.XCoordinateTextArea.Value = app.XPos;
        YPos           = 1.27  %         app.YCoordinateTextArea.Value = app.YPos;
        ZPos           = 0.62  %         app.ZCoordinateTextArea.Value = app.ZPos;
        PosCoVariance    = [0,0,0] % Just adding for now, need to be finalized eventually
        
        
        % Logging Variables
        PosTable = table()
        % app.PosTable.Properties.VariableNames = {'X' 'Y' 'Z' 'COVXX' 'COVYY' 'COVZZ' 'TimeStamp'};
        Log = []
        MarkerColors = struct(["Tag1"],{"Blue"}, ["Tag2"],{"Red"},["Tag3"],["Green"], ["Tag4"],{"Yellow"}); %#ok<NBRAK> 
        

        %Simulation Params
        SimOutput = NaN;
        antenna_locs = [[0,0]; [0,3]; [3,3]; [3,0]]; %2D input for now
        RSSI = zeros(5,1,'double');
        distances = zeros(4,1,'double');


   end
    
    methods (Access = public)
        
        % Writes Event logs in a multiline format to a .txt file
        function [] = LogWriter(app)
          DateString = datestr(datetime("now"));
          fid = fopen(fullfile(pwd, DateString+ "_EventLog.txt"),'w');
          for i = 1:length(app.Log)
            fprintf(fid,'%s\n', cell2mat(app.Log(i)));     
          end  
          fclose(fid);
        end
        
        % Writes Tag States in a csv format to a .txt file
        function [] = StateWriter(app)     %TO CHANGE and CALL
          DateString = datestr(datetime("now"));
          fid = fullfile(pwd, DateString+ "_StateLog.txt");
          writetable(app.PosTable, fid);
        end
        
        %%Stores tag state at current timestamp in an 8-element tabular format
        function [] = StoreStates(app)
            app.PosTable(app.StateCounter,:) = {app.TagNumber,datetime('now'),app.XPos,app.YPos,app.ZPos,app.PosCoVariance(1), app.PosCoVariance(2), app.PosCoVariance(3)};
        end
        
        function [] = Overlay(app, XVal, YVal,ZVal, ~) %%TO CHANGE
            % Convert XVal and YVal to Pixels
            OverlayPoint = scatter3(app.UIAxes, XVal*app.ScaleFactorX(2), YVal*app.ScaleFactorY(2), ZVal*app.ScaleFactorZ(2),100,'filled', 'Marker', 'o', 'LineWidth',2);
            OverlayPoint.MarkerFaceColor = app.MarkerColors.(app.TagNumber);
                 textscatter3(app.UIAxes,(XVal+0.2)*app.ScaleFactorX(2), (YVal+0.2)*app.ScaleFactorY(2), (ZVal+0.4)*app.ScaleFactorZ(2), string(app.TagNumber),'FontWeight','bold', 'ColorData',[1,1,1], 'FontSize', 22);
        end
       
    end
    
    methods (Access = private)
        
       
        function [] = triangulator3D(app)
             
         if app.TagNumber == "Tag1"
             pos      = [2.66; 3.89; 0.67]; %Row Vector [3x1]
         end
         if app.TagNumber == "Tag2"
             app.FeasiblilityFlag = 1
             pos    = [1.82; 2.23; 0.3]
         end
        if app.TagNumber == "Tag3"
            app.FeasiblilityFlag = 1
            pos    = [0.82; 4.23; 0.66]
        end
            %Store to global parameters
            
            if(app.FeasiblilityFlag == 1) %Feasibility loop
           
                 %===============CHANGE BOUNDS WHEN FINALIZED======================%
                if(pos>=[0;0;0] & pos<[app.RoomBoundX; app.RoomBoundY; app.RoomBoundZ])  %Boundedness check
                    app.FeasiblilityFlag = 1; %Set feasibility flag
                    app.FoundStateFlag   = 1; %Set found state flag
                    
                    %Assign coordinates to param set | Dispay in Text area
                    app.XPos = pos(1);
                    app.YPos = pos(2);
                    app.ZPos = pos(3);                    
                    app.XCoordinateTextArea.Value = sprintf("%f",app.XPos);
                    app.YCoordinateTextArea.Value = sprintf("%f",app.YPos);
                    app.ZCoordinateTextArea.Value = sprintf("%f",app.ZPos);
                    
                    StoreStates(app); %Store states if feasible
                    app.StateCounter = app.StateCounter+1;

                  if app.TagNumber == "Tag3"
                     app.FeasiblilityFlag = 0
                     app.FoundStateFlag   = 0
                     pos    = [0.82; 4.23; 0.66]
                     
                     app.XPos = pos(1);
                     app.YPos = pos(2);
                     app.ZPos = pos(3);                    
                     app.XCoordinateTextArea.Value = sprintf("%f",app.XPos);
                     app.YCoordinateTextArea.Value = sprintf("%f",app.YPos);
                     app.ZCoordinateTextArea.Value = sprintf("%f",app.ZPos);
                     StoreStates(app); %Store states if feasible
                     app.StateCounter = app.StateCounter+1;
                    end

               
                end
           else %If current states infeasible-> Search for feasible past state and display
                    app.FeasiblilityFlag  = 0; %Reset feasibility flag
                    app.FoundStateFlag    = 0; %Reset feasibility flag
                  
                    %Extract last Feasible value corresponding to app.TagNumber
                    PastState = tail(app.PosTable(app.PosTable.TagID(:) == app.TagNumber,:),1);   
                    app.XPos  = PastState.X;
                    app.YPos  = PastState.Y;
                    app.ZPos  = PastState.Z;

                    StoreStates(app); %Re-Log past states if current states infeasible
                    app.StateCounter = app.StateCounter+1; %Increment counter
                    app.XCoordinateTextArea.Value = sprintf("%f",app.XPos);
                    app.YCoordinateTextArea.Value = sprintf("%f",app.YPos);
                    app.ZCoordinateTextArea.Value = sprintf("%f",app.ZPos);
             end
         
            end


        function [] = SimModel(app)
            model = 'antenna_sim_21a';
            open(model)
            %% ====Simulation Params Setup===== %%
            FreqCarrier = 9.15e+08;
            Gr = 6; 
            Gt = 1.56; 
            Zin_r = 50; 
            Zin_t = 7.056465388996018e-01 + 2.916241413655430e+02i;
            lambdaCarrier = physconst('LightSpeed')/FreqCarrier;
            
            %Preset Distances for corroboration %
            R1 = 2.828;
            R2 = 2.236;
            R3 = 1.414;
            R4 = 2.236;
            Rtest = 1;

            set_param('antenna_sim_21a/Antenna_TX','Gr',num2str(Gt))
            set_param('antenna_sim_21a/Antenna_TX','Zin',num2str(Zin_t))
            set_param('antenna_sim_21a/Antenna_TX','CarrierFreqRad',num2str(FreqCarrier))
            set_param('antenna_sim_21a/PA','Zin',num2str(Zin_r))      
            set_param('antenna_sim_21a/Inport','CarrierFreq',num2str(FreqCarrier))
            
            set_param('antenna_sim_21a/Antenna_RX','Gr',num2str(Gr))
            set_param('antenna_sim_21a/Antenna_RX1','Gr',num2str(Gr))
            set_param('antenna_sim_21a/Antenna_RX2','Gr',num2str(Gr))
            set_param('antenna_sim_21a/Antenna_RX3','Gr',num2str(Gr))
            set_param('antenna_sim_21a/Antenna_RX4','Gr',num2str(Gr))
            set_param('antenna_sim_21a/Antenna_RX','Zin',num2str(Zin_r))
            set_param('antenna_sim_21a/Antenna_RX1','Zin',num2str(Zin_r))
            set_param('antenna_sim_21a/Antenna_RX2','Zin',num2str(Zin_r))
            set_param('antenna_sim_21a/Antenna_RX3','Zin',num2str(Zin_r))
            set_param('antenna_sim_21a/Antenna_RX4','Zin',num2str(Zin_r))
            set_param('antenna_sim_21a/Antenna_RX','CarrierFreqRad',num2str(FreqCarrier))
            set_param('antenna_sim_21a/Antenna_RX1','CarrierFreqRad',num2str(FreqCarrier))
            set_param('antenna_sim_21a/Antenna_RX2','CarrierFreqRad',num2str(FreqCarrier))
            set_param('antenna_sim_21a/Antenna_RX3','CarrierFreqRad',num2str(FreqCarrier))
            set_param('antenna_sim_21a/Antenna_RX4','CarrierFreqRad',num2str(FreqCarrier))
             
             
            set_param('antenna_sim_21a/FS_PathLoss1','Gain',num2str(lambdaCarrier/(4*pi*R1)))
            set_param('antenna_sim_21a/FS_PathLoss2','Gain',num2str(lambdaCarrier/(4*pi*R2)))
            set_param('antenna_sim_21a/FS_PathLoss3','Gain',num2str(lambdaCarrier/(4*pi*R3)))
            set_param('antenna_sim_21a/FS_PathLoss4','Gain',num2str(lambdaCarrier/(4*pi*R4)))
            set_param('antenna_sim_21a/FS_PathLossTest','Gain',num2str(lambdaCarrier/(4*pi*Rtest)))
              
            set_param('antenna_sim_21a/LNA','Zin',num2str(Zin_r'))
            set_param('antenna_sim_21a/LNA1','Zin',num2str(Zin_r'))
            set_param('antenna_sim_21a/LNA2','Zin',num2str(Zin_r'))
            set_param('antenna_sim_21a/LNA3','Zin',num2str(Zin_r'))
            set_param('antenna_sim_21a/LNA4','Zin',num2str(Zin_r'))
             
     
           set_param('antenna_sim_21a/RA1','CarrierFreq',num2str(FreqCarrier))
           set_param('antenna_sim_21a/RA2','CarrierFreq',num2str(FreqCarrier))
           set_param('antenna_sim_21a/RA3','CarrierFreq',num2str(FreqCarrier))
           set_param('antenna_sim_21a/RA4','CarrierFreq',num2str(FreqCarrier))
           set_param('antenna_sim_21a/RA5','CarrierFreq',num2str(FreqCarrier))
           
    
            %============SIMULATION CALL==================%
            app.SimOutput = sim(model);
            
            %Obtained Signal Strengths
            app.RSSI(1) = app.SimOutput.RSSI1(1);
            app.RSSI(2) = app.SimOutput.RSSI2(1);
            app.RSSI(3) = app.SimOutput.RSSI3(1);
            app.RSSI(4) = app.SimOutput.RSSI4(1);
            app.RSSI(5) = app.SimOutput.RSSI_test(1); %Unit Distance RSSI            
            %=============================================%
            
        end


        function [] = DistEstimator(app)
            
            A=app.RSSI(5); %dbm strength when length=1m; Original A = 5
            d0=5; %length of room
            for i = 1:4
                n(i) = -(app.RSSI(i)-A)/(10*log10(d0)); %constant
            end
            
            nhat = mean(n);
            
            for i = 1:4
                %RSSI(i) = input() %strength received from tag
                app.distances(i)= 10^((-app.RSSI(i)-A)/(10*nhat)); %distance
            end
            
        end

        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.GUI.WindowState = 'maximized';
            app.EventLogTextArea.Value = sprintf("%s", "Selecting Floorplan");
            
            %Use Matlab UI to fetch Floorplan Image file
%             [file,path] = uigetfile;
%             FLOORPLAN = imread(fullfile(path, file));
%             
%             %Axis-1: Image Display as Background
%             app.UIAxes.Position = [29,158,356,288];
%             image(app.UIAxes, FLOORPLAN)
%             app.UIAxes.Color  = 'None';
%             app.UIAxes.XColor = 'None';
%             app.UIAxes.YColor = 'None';
% 
%             %Axis-2: Image Display as Background
%             app.UIAxes_2.Position = [29,158,356,288];
%             app.UIAxes_2.Color  = 'None';
%             app.UIAxes_2.XColor = 'None';
%             app.UIAxes_2.YColor = 'None';
%             
%             %Augment Axis-2 limits
%             app.UIAxes_2.XLim = app.UIAxes.XLim;
%             app.UIAxes_2.YLim = app.UIAxes.YLim;      
            app.GUI.Name = 'RFID LOCATRON';
            app.UIAxes.Color    = [0.8,0.8,0.8];
            app.UIAxes.XColor = [0.25,0.25,0.25];
            app.UIAxes.YColor = [0.25,0.25,0.25];
            app.UIAxes.ZColor = [0.25,0.25,0.25];
           
%             app.UIAxes_2.Position = [29,158,356,288]
%             
             app.UIAxes.Position = [29,158,356,288];

            [file,path] = uigetfile;
            ROOMSTL = stlread(fullfile(path, file));
            trisurf(ROOMSTL,'Parent',app.UIAxes, 'EdgeColor','w', 'LineWidth', 1, 'FaceColor', 'k');
            hold(app.UIAxes, 'on')
           
                
          %Axis-2: Image Display as Background
            view(app.UIAxes,30,10)
            app.ScaleFactorX = app.UIAxes.XLim/app.RoomBoundX;
            app.ScaleFactorY = app.UIAxes.YLim/app.RoomBoundY;
            app.ScaleFactorZ = app.UIAxes.ZLim/app.RoomBoundZ;
            app.PosTable = table('Size',[app.StateBufferSz 8],'VariableTypes',{'string','datetime','double', 'double', 'double', 'double', 'double', 'double'}, ...
                                            'VariableNames', {'TagID', 'TimeStamp', 'X','Y','Z','CovXX', 'CovYY', 'CovZZ'});
            app.PosTable(1, :) = {"Tag1", datetime('now'), 0, 0, 0, 0, 0, 0};
            app.PosTable(2, :) = {"Tag2", datetime('now'), 0, 0, 0, 0, 0, 0};
            app.PosTable(3, :) = {"Tag3", datetime('now'), 0, 0, 0, 0, 0, 0};
            app.PosTable(4, :) = {"Tag4", datetime('now'), 0, 0, 0, 0, 0, 0};
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.GUI.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {475, 475};
                app.GridLayout.ColumnWidth = {'1x'};
                app.AugmentedRealityDisplay.Layout.Row = 2;
                app.AugmentedRealityDisplay.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.AugmentedRealityDisplay.Layout.Row = 1;
                app.AugmentedRealityDisplay.Layout.Column = 2;
            end
        end

        % Button pushed function: LocateButton
        function LocateButtonPushed(app, event)
%             When this button is pushed, call other callback functions
              app.EventLogTextArea.Value = [app.EventLogTextArea.Value;sprintf("Beginning Search")];
              app.LocateButton.BackgroundColor = [0.8,0.8,0.8];
                    
              %Location Estimation Begin
                
%                SimModel(app);
%                DistEstimator(app);
               triangulator3D(app);
                
%               Set/Reset app.FoundStateFlag using data received in another callback function
                if app.FoundStateFlag == 1
                    app.EventLogTextArea.Value = [app.EventLogTextArea.Value;sprintf("%s found", app.TagNumber)];
                    app.LocatedLampIndicator.Color = [0,1,0];
                    app.MissingLampIndicator.Color = [0.2,0,0];


                     Overlay(app,app.PosTable{app.StateCounter-1,3}, app.PosTable{app.StateCounter-1,4},app.PosTable{app.StateCounter-1,5})
                    
                else
                    app.EventLogTextArea.Value = [app.EventLogTextArea.Value;sprintf("%s not found. Displaying last position", app.TagNumber)];
                    app.LocatedLampIndicator.Color = [0,0.2,0];
                    app.MissingLampIndicator.Color = [1,0,0];
                    Overlay(app, app.PosTable{app.StateCounter-1,3}, app.PosTable{app.StateCounter-1,4},app.PosTable{app.StateCounter-1,5})
                    
                    %Display Last state of tag on plot
%                     Overlay(app, app.XPos, app.YPos,app.ZPos)

                    
                end
        end

        % Value changed function: SetNeighborCountKnob
        function NeighborCountFunc(app, event)
            app.LocateButton.BackgroundColor = [0.5,0.5,0.5];            
            app.NeighborCount = app.SetNeighborCountKnob.Value;
            app.EventLogTextArea.Value = [app.EventLogTextArea.Value;sprintf("Selected Neighbor Count:   %s",app.NeighborCount)];
        end

        % Value changed function: SelectTagDropDown
        function SelectTagDropDownValueChanged(app, event)
            app.LocateButton.BackgroundColor = [0.5,0.5,0.5];            
            app.TagNumber = app.SelectTagDropDown.Value;
            app.EventLogTextArea.Value = [app.EventLogTextArea.Value;sprintf("Selected Tag ID:    %s",app.TagNumber)];
        end

        % Value changed function: EventLogTextArea
        function EventLogTextAreaValueChanged(app, event)
          %% Callback does not execute when text area changed programmatically
            if (app.LogCounter<app.LogBufferSz)   
            app.LogCounter = app.LogCounter+1;     
           else
            %RESET COUNTER and STOP EXECUTION

            end
        end

        % Close request function: GUI
        function GUICloseRequest(app, event)
            app.EventLogTextArea.Value = [app.EventLogTextArea.Value;sprintf("Closing App")];
            app.Log = app.EventLogTextArea.Value;
            StateWriter(app)
            LogWriter(app)
            delete(app)            
        end

        % Value changed function: XCoordinateTextArea
        function XCoordinateTextAreaValueChanged(app, event)
            value = app.XCoordinateTextArea.Value;
            Overlay(app,value,app.YPos);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure('Visible', 'off');
            app.GUI.Name = 'Mortgage Calculator';
            app.GUI.CloseRequestFcn = createCallbackFcn(app, @GUICloseRequest, true);
            app.GUI.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.GUI);
            app.NumericalData = uipanel(app.GridLayout);
            app.TagSelector = uipanel(app.NumericalData);
            app.TagSelector.Title = 'ID Selection';
            app.SelectTagDropDownLabel = uilabel(app.TagSelector);
            app.SelectTagDropDownLabel.Text = 'Select Tag';
            app.SelectTagDropDown = uidropdown(app.TagSelector);
            app.SelectTagDropDown.Items = {'Tag1', 'Tag2', 'Tag3', 'Tag4'};
            app.SelectTagDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectTagDropDownValueChanged, true);
            app.SelectTagDropDown.Tag = '0001';
            app.SelectTagDropDown.Value = 'Tag1';
            app.TagCoordinatesPanel = uipanel(app.NumericalData);
            app.TagCoordinatesPanel.Title = 'Tag Coordinates';
            app.XCoordinateTextAreaLabel = uilabel(app.TagCoordinatesPanel);
            app.XCoordinateTextAreaLabel.Text = 'X-Coordinate';
            app.XCoordinateTextArea = uitextarea(app.TagCoordinatesPanel);
            app.XCoordinateTextArea.ValueChangedFcn = createCallbackFcn(app, @XCoordinateTextAreaValueChanged, true);
            app.YCoordinateTextAreaLabel = uilabel(app.TagCoordinatesPanel);
            app.YCoordinateTextAreaLabel.Text = 'Y-Coordinate';
            app.YCoordinateTextArea = uitextarea(app.TagCoordinatesPanel);
            app.ZCoordinateTextAreaLabel = uilabel(app.TagCoordinatesPanel);
            app.ZCoordinateTextAreaLabel.Text = 'Z-Coordinate';
            app.ZCoordinateTextArea = uitextarea(app.TagCoordinatesPanel);
            app.LocatedLampLabel = uilabel(app.NumericalData);
            app.LocatedLampLabel.Text = 'Located';
            app.LocatedLampIndicator = uilamp(app.NumericalData);
            app.MissingLampLabel = uilabel(app.NumericalData);
            app.MissingLampLabel.Text = 'Missing';
            app.MissingLampIndicator = uilamp(app.NumericalData);
            app.SetNeighborCountKnobLabel = uilabel(app.NumericalData);
            app.SetNeighborCountKnobLabel.Text = 'Set Neighbor Count';
            app.SetNeighborCountKnob = uiknob(app.NumericalData, 'discrete');
            app.SetNeighborCountKnob.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '', ''};
            app.SetNeighborCountKnob.ValueChangedFcn = createCallbackFcn(app, @NeighborCountFunc, true);
            app.SetNeighborCountKnob.Value = '1';
            app.LocateButton = uibutton(app.NumericalData, 'push');
            app.LocateButton.ButtonPushedFcn = createCallbackFcn(app, @LocateButtonPushed, true);
            app.LocateButton.Text = 'Locate';
            app.AugmentedRealityDisplay = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.AugmentedRealityDisplay);
            title(app.UIAxes, ' ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.EventLogTextAreaLabel = uilabel(app.AugmentedRealityDisplay);
            app.EventLogTextAreaLabel.Text = 'Event Log';
            app.EventLogTextArea = uitextarea(app.AugmentedRealityDisplay);
            app.EventLogTextArea.ValueChangedFcn = createCallbackFcn(app, @EventLogTextAreaValueChanged, true);
            % Show the figure after all components are created
            app.GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EECE5552_GUI_3D_Simulink

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end