classdef EECE5552_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI                        matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        NumericalData              matlab.ui.container.Panel
        LocateButton               matlab.ui.control.Button
        SetNeighborCountKnob       matlab.ui.control.DiscreteKnob
        SetNeighborCountKnobLabel  matlab.ui.control.Label
        MissingLampIndicator       matlab.ui.control.Lamp
        MissingLampLabel           matlab.ui.control.Label
        LocatedLampIndicator       matlab.ui.control.Lamp
        LocatedLampLabel           matlab.ui.control.Label
        TagCoordinatesPanel        matlab.ui.container.Panel
        ZCoordinateTextArea        matlab.ui.control.TextArea
        ZCoordinateTextAreaLabel   matlab.ui.control.Label
        YCoordinateTextArea        matlab.ui.control.TextArea
        YCoordinateTextAreaLabel   matlab.ui.control.Label
        XCoordinateTextArea        matlab.ui.control.TextArea
        XCoordinateTextAreaLabel   matlab.ui.control.Label
        TagSelector                matlab.ui.container.Panel
        SelectTagDropDown          matlab.ui.control.DropDown
        SelectTagDropDownLabel     matlab.ui.control.Label
        AugmentedRealityDisplay    matlab.ui.container.Panel
        EventLogTextArea           matlab.ui.control.TextArea
        EventLogTextAreaLabel      matlab.ui.control.Label
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
        FloorPlanImage             matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        RoomBoundX     = 3.0  % Room Length
        RoomBoundY     = 3.0  % Room Breadth
        RoomBoundZ     = 3.0  % Room Height
        NeighborCount  = 1    % Number of K-Nearest Neighbors needed when localizing Tag
        FoundStateFlag = 0    % Flag to store state for indicator lights. |||Initially zero -> Not Found|||
        TagNumber      = 'Tag1'    % Default value of tag to be tracked, updated in callback
        XPos           = 0.0  %         app.XCoordinateTextArea.Value = app.XPos;
        YPos           = 0.0  %         app.YCoordinateTextArea.Value = app.YPos;
        ZPos           = 0.0  %         app.ZCoordinateTextArea.Value = app.ZPos;
        PosVariance    = [0,0,0]
        PosTable       = table()
        Log = []
        MarkerColors = struct(['Tag1','Tag2','Tag3', 'Tag4'],['Blue','Red', 'Green','Yellow'])
        
        
   end
    
    methods (Access = public)
        
        function [] = triangulator(app, distances, antenna_locs)
            xyz = optimvar('xyz',3);
            % 3D Triangulation Equations
            eq1 = ((xyz(1) - antenna_locs(1,1))^2 + (xyz(2) - antenna_locs(1,2))^2 + (xyz(3) - antenna_locs(1,3))^2 == (distances(1))^2);
            eq2 = ((xyz(1) - antenna_locs(2,1))^2 + (xyz(2) - antenna_locs(2,2))^2 + (xyz(3) - antenna_locs(2,3))^2 == (distances(2))^2);
            eq3 = ((xyz(1) - antenna_locs(3,1))^2 + (xyz(2) - antenna_locs(3,2))^2 + (xyz(3) - antenna_locs(3,3))^2 == (distances(3))^2);
            eq4 = ((xyz(1) - antenna_locs(4,1))^2 + (xyz(2) - antenna_locs(4,2))^2 + (xyz(3) - antenna_locs(4,3))^2 == (distances(4))^2);
        
            prob = eqnproblem;
            prob.Equations.eq1 = eq1;
            prob.Equations.eq2 = eq2;
            prob.Equations.eq3 = eq3;
            prob.Equations.eq4 = eq4;
        
            x0.xyz = [0 0 0];
            [sol,~,~] = solve(prob,x0);
        
            % Return position 3D
            pos      = sol.xyz; %Row Vector [3x1]
            app.XPos = pos(1);
            app.YPos = pos(2);
            app.ZPos = pos(3);
            app.XCoordinateTextArea.Value = app.XPos;
            app.YCoordinateTextArea.Value = app.YPos;
            app.ZCoordinateTextArea.Value = app.ZPos;
        end
        
        %%Stores tag state at current timestamp in a 7element tabular format
        function [] = stateStorage(app)
            app.PosTable = [app.PosTable;table(app.Xpos,app.YPos,app.ZPos,app.PosVariance(1), app.PosVariance(2), app.PosVariance(3), datetime('now'))];
        end
        
        function overlayHandle = Overlay(app)
            
        end
        
        function plothandle = Plotter(app)
            
        end
    end
    
    methods (Access = private)
              
        function [] = LogWriter(app)
          DateString = datestr(datetime("now"));
          fid = fopen(fullfile(pwd, "Logs/", DateString+ ".txt"),'w');
          for i = 1:length(app.Log)
            fprintf(fid,'%s\n', app.Log(i));     
          end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EventLogTextArea.Value = [app.EventLogTextArea.Value;sprintf("Selecting Floorplan")];
            app.Log = [app.Log; app.EventLogTextArea.Value];
            [file,path] = uigetfile;
            FLOORPLAN = imread(fullfile(path, file));
            % Configure x- and y- axis
%             app.UIAxes.XLim = [0 60];
%             app.UIAxes.XDir = 'reverse';
%             app.UIAxes.YLim = [0 1];
            app.UIAxes.Position = [29,158,356,288];
            image(app.UIAxes, FLOORPLAN)
            app.UIAxes.Color  = 'None';
            app.UIAxes.XColor = 'None';
            app.UIAxes.YColor = 'None';
%             app.UIAxes.Title = '';
%             app.UIAxes.XLabel = '';
%             app.UIAxes.YLabel = '';
           
            app.UIAxes_2.Position = [29,158,356,288];
            app.UIAxes_2.Color  = 'None';
            app.UIAxes_2.XColor = 'None';
            app.UIAxes_2.YColor = 'None';
            
%             app.UIAxes_2.Title  = '';
%             app.UIAxes_2.XLabel = '';
%             app.UIAxes_2.YLabel = '';
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.GUI.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {475, 475};
                app.GridLayout.ColumnWidth = {'1x'};
                app.AugmentedRealityDisplay.Layout.Row = 2;
                app.AugmentedRealityDisplay.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.AugmentedRealityDisplay.Layout.Row = 1;
                app.AugmentedRealityDisplay.Layout.Column = 2;
            end
        end

        % Button pushed function: LocateButton
        function LocateButtonPushed(app, event)
%             When this button is pushed, call other callback functions
              app.EventLogTextArea.Value = [app.EventLogTextArea.Value;sprintf("Beginning Search")];
              app.LocateButton.BackgroundColor = [0.5,0.5,0.5];
                
%               Set/Reset app.FoundStateFlag using data received in another callback function
                if app.FoundStateFlag == 1
                    app.EventLogTextArea.Value = [app.EventLogTextArea.Value;sprintf("%s found", app.TagNumber)];
                    app.Log = [app.Log; app.EventLogTextArea.Value];
                    app.LocatedLampIndicator.Color = [0,1,0];
                    app.MissingLampIndicator.Color = [0.2,0,0];
                else
                    app.EventLogTextArea.Value = [app.EventLogTextArea.Value;sprintf("%s not found", app.TagNumber)];
                    app.Log = [app.Log; app.EventLogTextArea.Value];
                    app.LocatedLampIndicator.Color = [0,0.2,0];
                    app.MissingLampIndicator.Color = [1,0,0];
                end
                 
        end

        % Value changed function: SetNeighborCountKnob
        function NeighborCountFunc(app, event)
            app.LocateButton.BackgroundColor = [1,1,1];            
            app.NeighborCount = app.SetNeighborCountKnob.Value;
            app.EventLogTextArea.Value = [app.EventLogTextArea.Value;sprintf("Selected Neighbor Count:   %s",app.NeighborCount)];
                 
        end

        % Value changed function: SelectTagDropDown
        function SelectTagDropDownValueChanged(app, event)
            app.LocateButton.BackgroundColor = [1,1,1];            
            app.TagNumber = app.SelectTagDropDown.Value;
            app.EventLogTextArea.Value = [app.EventLogTextArea.Value;sprintf("Selected Tag ID:    %s",app.TagNumber)];
            app.Log = [app.Log; sprintf("Selected Tag ID:    %s",app.TagNumber)];
        end

        % Value changed function: EventLogTextArea
        function EventLogTextAreaValueChanged(app, event)
                     
        end

        % Close request function: GUI
        function GUICloseRequest(app, event)
            app.EventLogTextArea.Value = [app.EventLogTextArea.Value;sprintf("Closing App")];
            LogWriter(app)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure('Visible', 'off');
            app.GUI.Name = 'Mortgage Calculator';
            app.GUI.CloseRequestFcn = createCallbackFcn(app, @GUICloseRequest, true);
            app.GUI.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.GUI);
            app.NumericalData = uipanel(app.GridLayout);
            app.TagSelector = uipanel(app.NumericalData);
            app.TagSelector.Title = 'ID Selection';
            app.SelectTagDropDownLabel = uilabel(app.TagSelector);
            app.SelectTagDropDownLabel.Text = 'Select Tag';
            app.SelectTagDropDown = uidropdown(app.TagSelector);
            app.SelectTagDropDown.Items = {'Tag 1', 'Tag 2', 'Tag 3', 'Tag 4'};
            app.SelectTagDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectTagDropDownValueChanged, true);
            app.SelectTagDropDown.Tag = '0001';
            app.SelectTagDropDown.Value = 'Tag 1';
            app.TagCoordinatesPanel = uipanel(app.NumericalData);
            app.TagCoordinatesPanel.Title = 'Tag Coordinates';
            app.XCoordinateTextAreaLabel = uilabel(app.TagCoordinatesPanel);
            app.XCoordinateTextAreaLabel.Text = 'X-Coordinate';
            app.XCoordinateTextArea = uitextarea(app.TagCoordinatesPanel);
            app.YCoordinateTextAreaLabel = uilabel(app.TagCoordinatesPanel);
            app.YCoordinateTextAreaLabel.Text = 'Y-Coordinate';
            app.YCoordinateTextArea = uitextarea(app.TagCoordinatesPanel);
            app.ZCoordinateTextAreaLabel = uilabel(app.TagCoordinatesPanel);
            app.ZCoordinateTextAreaLabel.Text = 'Z-Coordinate';
            app.ZCoordinateTextArea = uitextarea(app.TagCoordinatesPanel);
            app.LocatedLampLabel = uilabel(app.NumericalData);
            app.LocatedLampLabel.Text = 'Located';
            app.LocatedLampIndicator = uilamp(app.NumericalData);
            app.MissingLampLabel = uilabel(app.NumericalData);
            app.MissingLampLabel.Text = 'Missing';
            app.MissingLampIndicator = uilamp(app.NumericalData);
            app.SetNeighborCountKnobLabel = uilabel(app.NumericalData);
            app.SetNeighborCountKnobLabel.Text = 'Set Neighbor Count';
            app.SetNeighborCountKnob = uiknob(app.NumericalData, 'discrete');
            app.SetNeighborCountKnob.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '', ''};
            app.SetNeighborCountKnob.ValueChangedFcn = createCallbackFcn(app, @NeighborCountFunc, true);
            app.SetNeighborCountKnob.Value = '1';
            app.LocateButton = uibutton(app.NumericalData, 'push');
            app.LocateButton.ButtonPushedFcn = createCallbackFcn(app, @LocateButtonPushed, true);
            app.LocateButton.Text = 'Locate';
            app.AugmentedRealityDisplay = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.AugmentedRealityDisplay);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.AugmentedRealityDisplay);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.EventLogTextAreaLabel = uilabel(app.AugmentedRealityDisplay);
            app.EventLogTextAreaLabel.Text = 'Event Log';
            app.EventLogTextArea = uitextarea(app.AugmentedRealityDisplay);
            app.EventLogTextArea.ValueChangedFcn = createCallbackFcn(app, @EventLogTextAreaValueChanged, true);
            app.FloorPlanImage = uiimage(app.GUI);
            app.FloorPlanImage.ImageSource = 'FLOORPLAN.jpg';
            % Show the figure after all components are created
            app.GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EECE5552_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end