classdef Test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button         matlab.ui.control.Button
        TextArea       matlab.ui.control.TextArea
        TextAreaLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
  
    end
    
    properties (Access = public)
      
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            model = 'antenna_sim_21a';
            open(model)
            FreqCarrier = 9.15e+08; % Operating Frequency - 915 MHz
            Gr = 6; % Gain of antenna receivers = 6 dBi
            Gt = 1.56; % Gain of RFID tag = 1.56 dBi
            Zin_r = 50; % Impedance of antennas
            Zin_t = 7.056465388996018e-01 + 2.916241413655430e+02i; % Impedance of tag
            
            % Distances to tag (current location (2,2))
            % [2.828 2.236 1.414 2.236]
            R1 = 2.828;
            R2 = 2.236;
            R3 = 1.414;
            R4 = 2.236;
            
            Rtest = 1; % For getting RSSI at 1 meter distance
            
            % Wavelength for free space given our operating frequency
            lambdaCarrier = physconst('LightSpeed')/FreqCarrier;
    
             %Tag Params Set
             set_param('antenna_sim_21a/Antenna_TX','Gr',num2str(Gt))
             set_param('antenna_sim_21a/Antenna_TX','Zin',num2str(Zin_t))
             set_param('antenna_sim_21a/Antenna_TX','CarrierFreq',num2str(FreqCarrier))
             set_param('antenna_sim_21a/PA','Zin',num2str(Zin_t'))      
             set_param('antenna_sim_21a/Inport','CarrierFreq',num2str(FreqCarrier))
             
             set_param('antenna_sim_21a/Antenna_RX','Gr',num2str(Gr))
             set_param('antenna_sim_21a/Antenna_RX1','Gr',num2str(Gr))
             set_param('antenna_sim_21a/Antenna_RX2','Gr',num2str(Gr))
             set_param('antenna_sim_21a/Antenna_RX3','Gr',num2str(Gr))
             set_param('antenna_sim_21a/Antenna_RX4','Gr',num2str(Gr))
             set_param('antenna_sim_21a/Antenna_RX','Zin',num2str(Zin_r))
             set_param('antenna_sim_21a/Antenna_RX1','Zin',num2str(Zin_r))
             set_param('antenna_sim_21a/Antenna_RX2','Zin',num2str(Zin_r))
             set_param('antenna_sim_21a/Antenna_RX3','Zin',num2str(Zin_r))
             set_param('antenna_sim_21a/Antenna_RX4','Zin',num2str(Zin_r))
             set_param('antenna_sim_21a/Antenna_RX','CarrierFreq',num2str(FreqCarrier))
             set_param('antenna_sim_21a/Antenna_RX1','CarrierFreq',num2str(FreqCarrier))
             set_param('antenna_sim_21a/Antenna_RX2','CarrierFreq',num2str(FreqCarrier))
             set_param('antenna_sim_21a/Antenna_RX3','CarrierFreq',num2str(FreqCarrier))
             set_param('antenna_sim_21a/Antenna_RX4','CarrierFreq',num2str(FreqCarrier))
             
             
             set_param('antenna_sim_21a/FS_PathLoss1','Gain',num2str(lambdaCarrier/(4*pi*R1)))
             set_param('antenna_sim_21a/FS_PathLoss2','Gain',num2str(lambdaCarrier/(4*pi*R2)))
             set_param('antenna_sim_21a/FS_PathLoss3','Gain',num2str(lambdaCarrier/(4*pi*R3)))
             set_param('antenna_sim_21a/FS_PathLoss4','Gain',num2str(lambdaCarrier/(4*pi*R4)))
             set_param('antenna_sim_21a/FS_PathLossTest','Gain',num2str(lambdaCarrier/(4*pi*Rtest)))
              
             set_param('antenna_sim_21a/LNA','Zin',num2str(Zin_r'))
             set_param('antenna_sim_21a/LNA1','Zin',num2str(Zin_r'))
             set_param('antenna_sim_21a/LNA2','Zin',num2str(Zin_r'))
             set_param('antenna_sim_21a/LNA3','Zin',num2str(Zin_r'))
             set_param('antenna_sim_21a/LNA4','Zin',num2str(Zin_r'))
             
     
            set_param('antenna_sim_21a/Outport1','CarrierFreq',num2str(FreqCarrier))
            set_param('antenna_sim_21a/Outport2','CarrierFreq',num2str(FreqCarrier))
            set_param('antenna_sim_21a/Outport3','CarrierFreq',num2str(FreqCarrier))
            set_param('antenna_sim_21a/Outport4','CarrierFreq',num2str(FreqCarrier))
            set_param('antenna_sim_21a/Outport5','CarrierFreq',num2str(FreqCarrier))
            
             
            SimOutput = sim(model);
            
         
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
              value = app.TextArea.Value;
              
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.TextArea.Value = "!12312312";
            figure(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end